classdef Filterdesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % Filterde...
        backbutton            matlab.ui.control.Button           % Back
        Panel                 matlab.ui.container.Panel          % Panel
        LabelNumericEditField matlab.ui.control.Label            % Desired ...
        LField                matlab.ui.control.NumericEditField % [2 6000]
        Label                 matlab.ui.control.Label            % Designed...
        NField                matlab.ui.control.NumericEditField % [1 6000]
        Label2                matlab.ui.control.Label            % Cut-off ...
        w0Field               matlab.ui.control.NumericEditField % [1.00000...
        Label3                matlab.ui.control.Label            % pass-ban...
        wpField               matlab.ui.control.NumericEditField % [5.00000...
        Label4                matlab.ui.control.Label            % stop-ban...
        wsField               matlab.ui.control.NumericEditField % [5.00000...
        Switch                matlab.ui.control.Switch           % Low pass...
        Label5                matlab.ui.control.Label            % Stop-Ban...
        stopweightField       matlab.ui.control.NumericEditField % [1 20]
        Label6                matlab.ui.control.Label            % Pass-Ban...
        passweightField       matlab.ui.control.NumericEditField % [1 20]
        UIAxes                matlab.ui.control.UIAxes           % Designed...
        LabelToggleSwitch     matlab.ui.control.Label            % Scale
        ToggleSwitch          matlab.ui.control.ToggleSwitch     % Log, Linear
        Label11               matlab.ui.control.Label            % LS Error
        errorfield            matlab.ui.control.EditField       
    end


    methods (Access = public)
        
        function Updategraph(app)
            
            % getting inputs
            L = app.LField.Value;
            N = app.NField.Value;
            w0 = app.w0Field.Value;
            wp = app.wpField.Value;
            ws = app.wsField.Value;
            if strcmp(app.Switch.Value,'Low pass')
                mode = 0;
            else
                mode = 1;
            end
            weights = app.stopweightField.Value;
            weightp = app.passweightField.Value;
            
        % error handling
        if ~mod(N,2)

            uialert(app.UIFigure,'N has to be odd - it was increased by 1','error');   
            N = N+1;
            app.NField.Value =N;
        end
        if N > L
            N = L-1;


            uialert(app.UIFigure,'N has to be smaller than L','error');
        end
        
        if ~(abs(w0-wp)+abs(w0-ws) <= abs(wp-ws) + 0.001)
            uialert(app.UIFigure,'w0 has to lie between wp and ws - it was put in the middle','error');            
            if wp<ws
                w0 = wp + abs(wp-ws)/2;
            else
                w0 = wp - abs(wp-ws)/2;
            end
            app.w0Field.Value = w0;
        end
        if mode ==0
            if wp>ws
                wp = abs(ws-w0);
                app.wpField.Value = wp;
                uialert(app.UIFigure,'in LPF wp is smaller than ws - it was set smaller than ws by w0','error');            
            end
            [h, Hw,w,D,error] = myFilterDesigner(N,L,w0,wp,ws,weightp,weights,mode);
            plot(app.UIAxes,w,abs(Hw));
            hold(app.UIAxes,'on')
            plot(app.UIAxes,w,abs(D));
            hold(app.UIAxes,'off');
            app.errorfield.Value = num2str(error);
        else
            if wp<ws
                wp = ws+w0;
                app.wpField.Value = wp;
                uialert(app.UIFigure,'in HPF wp is larger than ws - it was set larger than ws by w0','error');            
            end
            [h, Hw,w,D,error] = myFilterDesigner(N,L,w0,wp,ws,weights,weightp,mode);
            plot(app.UIAxes,w,abs(Hw));
            hold(app.UIAxes,'on')
            plot(app.UIAxes,w,abs(D));
            hold(app.UIAxes,'off');
            app.errorfield.Value = num2str(error);
            
        end
    end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % backbutton button pushed function
        function backbuttonButtonPushed(app)
            Gate;
            close(app.UIFigure);            
        end

        % LField value changed function
        function LFieldValueChanged(app)
        Updategraph(app);
        end

        % NField value changed function
        function NFieldValueChanged(app)
                    Updategraph(app);

        end

        % ToggleSwitch value changed function
        function ToggleSwitchValueChanged(app)
            value = app.ToggleSwitch.Value;
            if strcmp(value, 'Linear')
            set(app.UIAxes,'yscale','linear');
            else
            set(app.UIAxes,'yscale','log');
            end            
        end

        % Switch value changed function
        function SwitchValueChanged(app)
            Updategraph(app);          
        end

        % stopweightField value changed function
        function stopweightFieldValueChanged(app)
            Updategraph(app);
        end

        % NumericEditField value changed function
        function NumericEditFieldValueChanged(app)
            Updategraph(app);
        end

        % w0Field value changed function
        function w0FieldValueChanged(app)
            Updategraph(app);
        end

        % wpField value changed function
        function wpFieldValueChanged(app)
            Updategraph(app);
        end

        % wsField value changed function
        function wsFieldValueChanged(app)
            Updategraph(app);
        end

        % passweightField value changed function
        function passweightFieldValueChanged(app)
            Updategraph(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Filterdesigner';
            setAutoResize(app, app.UIFigure, true)
            app.backbutton = uibutton(app.UIFigure, 'push');
            app.backbutton.ButtonPushedFcn = createCallbackFcn(app, @backbuttonButtonPushed);
            app.backbutton.Text = 'Back';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Desired Filter Length (L)';
            app.LField = uieditfield(app.Panel, 'numeric');
            app.LField.ValueChangedFcn = createCallbackFcn(app, @LFieldValueChanged);
            app.LField.Limits = [2 6000];
            app.LField.Value = 2;
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Designed Filter Length (N)';
            app.NField = uieditfield(app.Panel, 'numeric');
            app.NField.ValueChangedFcn = createCallbackFcn(app, @NFieldValueChanged);
            app.NField.Limits = [1 6000];
            app.NField.Value = 1;
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'Cut-off frequency(0~1)';
            app.w0Field = uieditfield(app.Panel, 'numeric');
            app.w0Field.ValueChangedFcn = createCallbackFcn(app, @w0FieldValueChanged);
            app.w0Field.Limits = [0.1 1];
            app.w0Field.Value = 0.1;
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'pass-band frequency(0~1)';
            app.wpField = uieditfield(app.Panel, 'numeric');
            app.wpField.ValueChangedFcn = createCallbackFcn(app, @wpFieldValueChanged);
            app.wpField.Limits = [0.05 1];
            app.wpField.Value = 0.05;
            app.Label4 = uilabel(app.Panel);
            app.Label4.Text = 'stop-band frequency(0~1)';
            app.wsField = uieditfield(app.Panel, 'numeric');
            app.wsField.ValueChangedFcn = createCallbackFcn(app, @wsFieldValueChanged);
            app.wsField.Limits = [0.05 Inf];
            app.wsField.Value = 0.15;
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.Items = {'Low pass', 'High pass'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged);
            app.Switch.Value = 'Low pass';
            app.Label5 = uilabel(app.Panel);
            app.Label5.Text = 'Stop-Band Weight (1 for unweighted)';
            app.stopweightField = uieditfield(app.Panel, 'numeric');
            app.stopweightField.ValueChangedFcn = createCallbackFcn(app, @stopweightFieldValueChanged);
            app.stopweightField.Limits = [1 20];
            app.stopweightField.Value = 1;
            app.Label6 = uilabel(app.Panel);
            app.Label6.Text = 'Pass-Band Weight (1 for unweighted)';
            app.passweightField = uieditfield(app.Panel, 'numeric');
            app.passweightField.ValueChangedFcn = createCallbackFcn(app, @passweightFieldValueChanged);
            app.passweightField.Limits = [1 20];
            app.passweightField.Value = 1;
            app.Label11 = uilabel(app.Panel);
            app.Label11.Text = 'LS Error';
            app.errorfield = uieditfield(app.Panel, 'text');
            app.errorfield.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Designed vs Desired');
            xlabel(app.UIAxes, 'Normalized Frequency');
            ylabel(app.UIAxes, 'Magnitude');
            app.LabelToggleSwitch = uilabel(app.UIFigure);
            app.LabelToggleSwitch.Text = 'Scale';
            app.ToggleSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ToggleSwitch.Items = {'Log', 'Linear'};
            app.ToggleSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleSwitchValueChanged);
            app.ToggleSwitch.Value = 'Linear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Filterdesigner()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end